classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        cameraTab                     matlab.ui.container.Tab
        pixelflyAxes                  matlab.ui.control.UIAxes
        thorcam1Axes                  matlab.ui.control.UIAxes
        LabelNumericEditField         matlab.ui.control.Label
        pixeflyExposureEdit           matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        thorcam1ExposureEdit          matlab.ui.control.NumericEditField
        pixelflyPreviewToggle         matlab.ui.control.StateButton
        thorcam1PreviewToggle         matlab.ui.control.StateButton
        ImageInfoTextAreaLabel        matlab.ui.control.Label
        ImageInfoTextArea             matlab.ui.control.TextArea
        Tab2                          matlab.ui.container.Tab
        StateButton6                  matlab.ui.control.StateButton
        StateButton7                  matlab.ui.control.StateButton
        Tab3                          matlab.ui.container.Tab
        LabelListBox                  matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        AddactionButton               matlab.ui.control.Button
        systemStatus                  matlab.ui.container.Tab
        PSUTab                        matlab.ui.container.Tab
        LabelTextArea                 matlab.ui.control.Label
        circularCoilCurrentIndicator  matlab.ui.control.TextArea
        Label2                        matlab.ui.control.Label
        rectangularCoilCurrentIndicator  matlab.ui.control.TextArea
        LogTab                        matlab.ui.container.Tab
        OutputsTextAreaLabel          matlab.ui.control.Label
        OutputsTextArea               matlab.ui.control.TextArea
        DDSTab                        matlab.ui.container.Tab
        DDSCOMDropDownLabel           matlab.ui.control.Label
        DDSCOMDropDown                matlab.ui.control.DropDown
        TabGroup2                     matlab.ui.container.TabGroup
        RampOptionsTab                matlab.ui.container.Tab
        StartFreqEditFieldLabel       matlab.ui.control.Label
        StartFreqEditField            matlab.ui.control.NumericEditField
        EndFreqEditFieldLabel         matlab.ui.control.Label
        EndFreqEditField              matlab.ui.control.NumericEditField
        dfEditFieldLabel              matlab.ui.control.Label
        dfEditField                   matlab.ui.control.NumericEditField
        EndFreqEditField_3Label       matlab.ui.control.Label
        EndFreqEditField_3            matlab.ui.control.NumericEditField
        ToggleRampButton              matlab.ui.control.StateButton
        SetFreqTab                    matlab.ui.container.Tab
        InitDDSButton                 matlab.ui.control.Button
        ExtClockCheckBox              matlab.ui.control.CheckBox
        ParamSweepsTab                matlab.ui.container.Tab
        FirstParamDropDownLabel       matlab.ui.control.Label
        FirstParamDropDown            matlab.ui.control.DropDown
        SecondParamDropDownLabel      matlab.ui.control.Label
        SecondParamDropDown           matlab.ui.control.DropDown
        coolingState                  matlab.ui.control.StateButton
        repumpState                   matlab.ui.control.StateButton
        masterState                   matlab.ui.control.StateButton
        LabelLamp                     matlab.ui.control.Label
        masterLockIndicator           matlab.ui.control.Lamp
        repumpState_2                 matlab.ui.control.StateButton
        LoadMOTButton                 matlab.ui.control.Button
        UnloadMOTButton               matlab.ui.control.Button
        Button3                       matlab.ui.control.Button
        DEBUGSwitchLabel              matlab.ui.control.Label
        DEBUGSwitch                   matlab.ui.control.ToggleSwitch
    end

    properties (Access = public)
        channelTable %configuration of hardware channels
        DEBUG=1 %app DEBUG mode, 1/0 for developing at home 
        pixelfly_exposure;
        pixelfly_vid; %pixelfly configuration object
        pixelfly_src;%pixelfly configuration object
        circCoilConn;% connection object to circular AHH coils
        thorcam1; %thorlabs1camera
        thorcam1ImageData; %thorcam1 image
    end

    properties (Access = private)
        myTimer %timer for periodically calculating the image fitting properties
        xVec; %x coord vec for images
        yVec;% y coord vec for images
        testGaussian; %for testing images 
    end

    methods (Access = private)
    
        function circCoilCurrentCheck(app)
            app.circularCoilCurrentIndicator.Value='hello';
        end
        
        function cameraImageFitting(app)
            
            if app.thorcam1PreviewToggle.Value && app.DEBUG
                p=fitImageGaussian2D(app.xVec,app.yVec,app.testGaussian,1);
            elseif app.thorcam1PreviewToggle.Value && ~app.DEBUG
                im = app.thorcam1ImageData;
                
                if ~isempty(im)
                    [xsize,ysize]=size(app.thorcam1ImageData);
                    xvec=linspace(0,1,xsize);
                    yvec=linspace(0,1,ysize);
                    p=fitImageGaussian2D(xvec,yvec,app.thorcam1ImageData,1);
                else
                    disp('no image!');
                    p=zeros(1,7);
                end
                
            end
            if ~app.thorcam1PreviewToggle.Value
                p=zeros(1,7);
            end
                app.ImageInfoTextArea.Value={'Thorcam data:',...
                                            ['Integrated intensity: ' num2str(p(7))],...
                                            ['Maximum intensity: ' num2str(p(2))],...
                                            ['Center of Gaussian: ' num2str(p(3)) ',' num2str(p(4))],...
                                            ['Width of Gaussian in x: ' num2str(p(5))],...
                                            ['Width of Gaussian in y: ' num2str(p(6))]};
            
        end
        
        function [x,y,Z]=makeTestGaussian(app)
            %function to generate a 2d gaussian for testing purposes
            %x,y are vectors of coordinates, Z is a 2D image
            
            x=linspace(0,1,100);y=linspace(0,1,100);
            [X,Y]=ndgrid(x,y);
            sigma=0.1;
            Z=exp(-(X-0.5).^2/2/sigma^2)*exp(-(Y-.5).^2/2/sigma^2);
            
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
                        s = load('D:\Box Sync\Box Sync\Lab\ExpCold\FPGA control\ControlSystem\Code\Configurations\channelTable.mat'); %load channel configuration file
                        app.channelTable = s.channelTable;
            app.myTimer=timer('StartDelay',1,'Period',1,'ExecutionMode','fixedSpacing');
            app.myTimer.TimerFcn=@(x,y)app.cameraImageFitting;
            [x,y,Z]=makeTestGaussian(app);
            app.testGaussian=Z;
            app.xVec=x;
            app.yVec=y;
            start(app.myTimer);
            if ~app.DEBUG
            [vid,src]=pixelflySetup(100); %configure pixelfly
            app.pixelfly_vid=vid;
            app.pixelfly_src=src;
            
            psuSetup; %run AHH PSU setup script
            app.circCoilConn=tcpip('10.10.10.102',8462); %open connection to the circular PSU
            fopen(app.circCoilConn);
            end


        end

        % Value changed function: DEBUGSwitch
        function DEBUGSwitchValueChanged(app, event)
            value = app.DEBUGSwitch.Value;
            if strcmp(value,'On')
                app.DEBUG=1;
            else 
                app.DEBUG=0;
            end
            
            disp(['DEBUG MODE: ' num2str(app.DEBUG)]);
        end

        % Button pushed function: LoadMOTButton
        function LoadMOTButtonPushed(app, event)
            seqUpload(LoadMotSeq(app.channelTable),app.DEBUG); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.myTimer);
            delete(app);
            
        end

        % Button pushed function: UnloadMOTButton
        function UnloadMOTButtonPushed(app, event)
            seqUpload(UnloadMotSeq(app.channelTable),app.DEBUG); 
        end

        % Value changed function: coolingState
        function coolingStateToggle(app, event)
            value = app.coolingState.Value;
            seqUpload(channelToggleSeq(app.channelTable.PhysicalName{'cooling'},value),app.DEBUG); 
        end

        % Value changed function: pixeflyExposureEdit
        function pixeflyExposureEditValueChanged(app, event)
            value = app.pixeflyExposureEdit.Value;
            exposure=value;
        end

        % Value changed function: pixelflyPreviewToggle
        function pixelflyPreviewToggleValueChanged(app, event)
            state = app.pixelflyPreviewToggle.Value;
            if app.DEBUG~=1
                if isempty(app.pixelfly_vid)
                    [vid,src]=pixelflySetup(100); %configure pixelfly
                    app.pixelfly_vid=vid;
                    app.pixelfly_src=src;
                end
            vidRes = app.pixelfly_vid.VideoResolution; 
            nBands = app.pixelfly_vid.NumberOfBands; 
            im = image(app.pixelflyAxes,zeros(vidRes(2), vidRes(1), nBands)); 
            end 
            
            if app.DEBUG==1 && state ==1
                im = image(app.pixelflyAxes,100*rand(1024,1024));
            elseif app.DEBUG==1 && state ==0
                im = image(app.pixelflyAxes,zeros(1024,1024));
            end
            
            if state == 1 && app.DEBUG~=1
        	preview(app.pixelfly_vid,im);
            elseif state == 0 && app.DEBUG~=1
                stoppreview(app.pixelfly_vid);
            end
        end

        % Value changed function: repumpState
        function repumpStateValueChanged(app, event)
            value = app.repumpState.Value;
             seqUpload(channelToggleSeq(app.channelTable.PhysicalName{'repump'},value),app.DEBUG); 
        end

        % Value changed function: thorcam1PreviewToggle
        function thorcam1PreviewToggleValueChanged(app, event)
            stateThorcam = app.thorcam1PreviewToggle.Value;
            disp(stateThorcam)
            % Debug block: runs this block if DEBUG switch is on
            if app.DEBUG==1 && stateThorcam==1
                im = imagesc(app.thorcam1Axes,app.testGaussian);
                axis(im.Parent, 'image')
                axis(im.Parent, 'tight')
            elseif app.DEBUG==1 && stateThorcam==0
                im = image(app.thorcam1Axes,zeros(65,65));
            end
            
            
            if stateThorcam == 1 && app.DEBUG~=1
                im = image(app.thorcam1Axes,'CDataMapping','scaled');                     % Set axis to show the figure
                axis(im.Parent, 'image')                                                  % Configure axes aspect
                axis(im.Parent, 'tight')                                                  % Configure axes aspect
            
                [app.thorcam1, app.thorcam1ImageData, err] = InitiateCamera(im,1.7);            % Starts thorlabs camera live capture
                
                while stateThorcam == 1 || strcmp(err, uc480.Defines.Status.NO_SUCCESS)
                    [err,CamImage] = GetImage(app.thorcam1, app.thorcam1ImageData);
                    if ~isempty(CamImage)
                         im.CData = CamImage;
                         %contour()
                    end            
                    drawnow;
                end
                
                
            elseif stateThorcam == 0 && app.DEBUG~=1
                 im = imagesc(app.thorcam1Axes,zeros(65,65));
                 CloseCamera(app.thorcam1, app.thorcam1ImageData);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ColdAtomsLab';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.cameraTab = uitab(app.TabGroup);
            app.cameraTab.Title = 'Cameras';
            app.pixelflyAxes = uiaxes(app.cameraTab);
            title(app.pixelflyAxes, 'PixelFly')
            xlabel(app.pixelflyAxes, 'X')
            ylabel(app.pixelflyAxes, 'Y')
            app.thorcam1Axes = uiaxes(app.cameraTab);
            title(app.thorcam1Axes, 'Thorcam')
            xlabel(app.thorcam1Axes, 'X')
            ylabel(app.thorcam1Axes, 'Y')
            app.LabelNumericEditField = uilabel(app.cameraTab);
            app.LabelNumericEditField.Text = 'Exposure [uS]';
            app.pixeflyExposureEdit = uieditfield(app.cameraTab, 'numeric');
            app.pixeflyExposureEdit.ValueChangedFcn = createCallbackFcn(app, @pixeflyExposureEditValueChanged, true);
            app.Label = uilabel(app.cameraTab);
            app.Label.Text = 'Exposure [uS]';
            app.thorcam1ExposureEdit = uieditfield(app.cameraTab, 'numeric');
            app.thorcam1ExposureEdit.Limits = [0 Inf];
            app.pixelflyPreviewToggle = uibutton(app.cameraTab, 'state');
            app.pixelflyPreviewToggle.ValueChangedFcn = createCallbackFcn(app, @pixelflyPreviewToggleValueChanged, true);
            app.pixelflyPreviewToggle.Text = 'Pixelfly preview';
            app.thorcam1PreviewToggle = uibutton(app.cameraTab, 'state');
            app.thorcam1PreviewToggle.ValueChangedFcn = createCallbackFcn(app, @thorcam1PreviewToggleValueChanged, true);
            app.thorcam1PreviewToggle.Text = 'Thorcam preview';
            app.ImageInfoTextAreaLabel = uilabel(app.cameraTab);
            app.ImageInfoTextAreaLabel.Text = 'Image Info';
            app.ImageInfoTextArea = uitextarea(app.cameraTab);
            app.ImageInfoTextArea.Value = {''; ''};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Lasers';
            app.StateButton6 = uibutton(app.Tab2, 'state');
            app.StateButton7 = uibutton(app.Tab2, 'state');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Sequencer';
            app.LabelListBox = uilabel(app.Tab3);
            app.LabelListBox.Text = 'List Box';
            app.ListBox = uilistbox(app.Tab3);
            app.AddactionButton = uibutton(app.Tab3, 'push');
            app.AddactionButton.Text = 'Add action...';
            app.systemStatus = uitab(app.TabGroup);
            app.systemStatus.Title = 'Status';
            app.PSUTab = uitab(app.TabGroup);
            app.PSUTab.Title = 'PSU';
            app.LabelTextArea = uilabel(app.PSUTab);
            app.LabelTextArea.Text = 'Circular AAH coil current';
            app.circularCoilCurrentIndicator = uitextarea(app.PSUTab);
            app.Label2 = uilabel(app.PSUTab);
            app.Label2.Text = 'Rectangular AAH coil current';
            app.rectangularCoilCurrentIndicator = uitextarea(app.PSUTab);
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.OutputsTextAreaLabel = uilabel(app.LogTab);
            app.OutputsTextAreaLabel.Text = 'Outputs';
            app.OutputsTextArea = uitextarea(app.LogTab);
            app.DDSTab = uitab(app.TabGroup);
            app.DDSTab.Title = 'DDS';
            app.DDSCOMDropDownLabel = uilabel(app.DDSTab);
            app.DDSCOMDropDownLabel.Text = 'DDS COM';
            app.DDSCOMDropDown = uidropdown(app.DDSTab);
            app.TabGroup2 = uitabgroup(app.DDSTab);
            app.RampOptionsTab = uitab(app.TabGroup2);
            app.RampOptionsTab.Title = 'Ramp Options';
            app.StartFreqEditFieldLabel = uilabel(app.RampOptionsTab);
            app.StartFreqEditFieldLabel.Text = 'Start Freq';
            app.StartFreqEditField = uieditfield(app.RampOptionsTab, 'numeric');
            app.EndFreqEditFieldLabel = uilabel(app.RampOptionsTab);
            app.EndFreqEditFieldLabel.Text = 'End Freq';
            app.EndFreqEditField = uieditfield(app.RampOptionsTab, 'numeric');
            app.dfEditFieldLabel = uilabel(app.RampOptionsTab);
            app.dfEditFieldLabel.Text = 'df';
            app.dfEditField = uieditfield(app.RampOptionsTab, 'numeric');
            app.EndFreqEditField_3Label = uilabel(app.RampOptionsTab);
            app.EndFreqEditField_3Label.Text = 'End Freq';
            app.EndFreqEditField_3 = uieditfield(app.RampOptionsTab, 'numeric');
            app.ToggleRampButton = uibutton(app.RampOptionsTab, 'state');
            app.ToggleRampButton.Text = 'Toggle Ramp';
            app.SetFreqTab = uitab(app.TabGroup2);
            app.SetFreqTab.Title = 'Set Freq';
            app.InitDDSButton = uibutton(app.DDSTab, 'push');
            app.InitDDSButton.Text = 'Init DDS';
            app.ExtClockCheckBox = uicheckbox(app.DDSTab);
            app.ExtClockCheckBox.Text = 'Ext Clock';
            app.ParamSweepsTab = uitab(app.TabGroup);
            app.ParamSweepsTab.Title = 'Param Sweeps';
            app.FirstParamDropDownLabel = uilabel(app.ParamSweepsTab);
            app.FirstParamDropDownLabel.Text = 'First Param';
            app.FirstParamDropDown = uidropdown(app.ParamSweepsTab);
            app.SecondParamDropDownLabel = uilabel(app.ParamSweepsTab);
            app.SecondParamDropDownLabel.Text = 'Second Param';
            app.SecondParamDropDown = uidropdown(app.ParamSweepsTab);
            app.coolingState = uibutton(app.UIFigure, 'state');
            app.coolingState.ValueChangedFcn = createCallbackFcn(app, @coolingStateToggle, true);
            app.coolingState.Text = 'Cooling';
            app.repumpState = uibutton(app.UIFigure, 'state');
            app.repumpState.ValueChangedFcn = createCallbackFcn(app, @repumpStateValueChanged, true);
            app.repumpState.Text = 'Repump';
            app.masterState = uibutton(app.UIFigure, 'state');
            app.masterState.Text = 'Master';
            app.LabelLamp = uilabel(app.UIFigure);
            app.LabelLamp.Text = 'Master Lock';
            app.masterLockIndicator = uilamp(app.UIFigure);
            app.repumpState_2 = uibutton(app.UIFigure, 'state');
            app.repumpState_2.Text = 'Imaging';
            app.LoadMOTButton = uibutton(app.UIFigure, 'push');
            app.LoadMOTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMOTButtonPushed, true);
            app.LoadMOTButton.Text = 'Load MOT';
            app.UnloadMOTButton = uibutton(app.UIFigure, 'push');
            app.UnloadMOTButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadMOTButtonPushed, true);
            app.UnloadMOTButton.Text = 'Unload MOT';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.Text = 'Button3';
            app.DEBUGSwitchLabel = uilabel(app.UIFigure);
            app.DEBUGSwitchLabel.Text = 'DEBUG';
            app.DEBUGSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DEBUGSwitch.ValueChangedFcn = createCallbackFcn(app, @DEBUGSwitchValueChanged, true);
            app.DEBUGSwitch.Value = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end